classdef PNEUMOTECH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        index     matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Data changed function: index
        function indexDataChanged(app, event)
           
            
            data = event.Data;
            rfis = readfis('../model/riskFis');
            tfis = readfis('../model/typeFis');

            riskInput = str2double({data.I1 data.I2 data.I3 data.I4 data.I5 data.I6 data.I7 data.I8 data.I9 data.I10 data.I11 data.I12 data.I13 data.I14 data.I15 data.I16 data.I17 data.I18 data.I19});            
            riskOutput = evalfis(rfis,riskInput);
            typeInput = str2double({data.I1 data.I2 data.I7 data.I20 data.I21});
            typeOutput = evalfis(tfis,typeInput); 

              if (riskOutput <= 0)
                   riskLevel ="None";
              elseif (riskOutput > 0) && (riskOutput <= 30)
                   riskLevel ="Mild";
              elseif (riskOutput > 30) && (riskOutput <= 70)
                   riskLevel ="Moderate";
              else 
                  riskLevel ="Severe";
              end
              
              if (riskOutput > 0) && (typeOutput <= 30)
                  pType = "Community-Acquired Pneumonia";
              elseif (riskOutput > 0) && (typeOutput > 30) && (typeOutput <= 70)
                  pType = "Hospital-Acquired Pneumonia";
              elseif (riskOutput > 0) && (typeOutput < 70)
                  pType = "Ventilator-Acquired Pneumonia";
              else  
                  pType = "None";   
              end

              app.index.Data = {riskLevel pType};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.index = uihtml(app.UIFigure);
            app.index.HTMLSource = 'views/assessment.html';
            app.index.DataChangedFcn = createCallbackFcn(app, @indexDataChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PNEUMOTECH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end