classdef PNEUMOTECH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        assessment  matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Data changed function: assessment
        function assessmentDataChanged(app, event)
           
            
            data = event.Data;
            rfis = readfis('riskFis');
            tfis = readfis('typeFis');

            riskInput = str2double({data.I1 data.I2 data.I3 data.I4 data.I5 data.I6 data.I7 data.I8 data.I9 data.I10 data.I11 data.I12 data.I13 data.I14 data.I15 data.I16 data.I17 data.I18 data.I19});            
            riskOutput = evalfis(rfis,riskInput);
            typeInput = str2double({data.I1 data.I2 data.I7 data.I20 data.I21});
            typeOutput = evalfis(tfis,typeInput); 

              if (riskOutput <= 0)
                   riskLevel ="None";
              elseif (riskOutput > 0) && (riskOutput <= 30)
                   riskLevel ="Mild";
              elseif (riskOutput > 30) && (riskOutput <= 70)
                   riskLevel ="Moderate";
              else 
                  riskLevel ="Severe";
              end
              
              if (riskOutput > 0) && (typeOutput <= 30)
                  pType = "Community-Acquired Pneumonia";
              elseif (riskOutput > 0) && (typeOutput > 30) && (typeOutput <= 70)
                  pType = "Hospital-Acquired Pneumonia";
              elseif (riskOutput > 0) && (typeOutput > 70)
                  pType = "Ventilator-Acquired Pneumonia";
              else  
                  pType = "None";   
              end
              
              if (riskLevel == "Mild")
                  advice = "You have mild risk of having Pneumonia. It is advised to  see doctor to prevent further complications. Over-the-counter medications can also be used to relieve symptoms, such as antihistamines for nasal congestion or cough medications to help ease the cough and loosen any mucus. You can also drink plenty of fluids and take fever-reducing medicine if you have a fever.";
              elseif (riskLevel == "Moderate")
                  advice = "You have moderate risk of having Pneumonia. You should seek care in a hospital so that your doctor may prescribe appropriate medications for your symptoms and provide you advice on how to avoid further issues.";
              elseif (riskLevel == "Severe")
                  advice = "You have severe risk of having Pneumonia. This means your health and environment have made you more susceptible to developing Pneumonia. It is strongly advised that you get medical attention as soon as possible.";
              else
                  advice = "None";
              end

              app.assessment.Data = {riskLevel pType advice};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.assessment = uihtml(app.UIFigure);
            app.assessment.HTMLSource = 'index.html';
            app.assessment.DataChangedFcn = createCallbackFcn(app, @assessmentDataChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PNEUMOTECH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end